classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        KSliderLabel               matlab.ui.control.Label
        KSlider_16                 matlab.ui.control.Slider
        KSlider_2Label             matlab.ui.control.Label
        KSlider_14                 matlab.ui.control.Slider
        kSliderLabel               matlab.ui.control.Label
        kSlider_12                 matlab.ui.control.Slider
        kSlider_2Label             matlab.ui.control.Label
        kSlider_6                  matlab.ui.control.Slider
        kSlider_3Label             matlab.ui.control.Label
        kSlider_3                  matlab.ui.control.Slider
        kSlider_4Label             matlab.ui.control.Label
        kSlider_1                  matlab.ui.control.Slider
        SliderLabel                matlab.ui.control.Label
        Slider_600                 matlab.ui.control.Slider
        Slider_2Label              matlab.ui.control.Label
        Slider_310                 matlab.ui.control.Slider
        Slider_3Label              matlab.ui.control.Label
        Slider_170                 matlab.ui.control.Slider
        FilterTypeDropDownLabel    matlab.ui.control.Label
        FilterTypeDropDown         matlab.ui.control.DropDown
        inputsignalButton          matlab.ui.control.Button
        UIAxes2                    matlab.ui.control.UIAxes
        baseButton                 matlab.ui.control.Button
        playoriginalButton         matlab.ui.control.Button
        FrequencyDomainButton      matlab.ui.control.Button
        DBLabel                    matlab.ui.control.Label
        DBLabel_2                  matlab.ui.control.Label
        DBLabel_3                  matlab.ui.control.Label
        TimeDomainButton           matlab.ui.control.Button
        PlayCompositeButton        matlab.ui.control.Button
        FrequencyDomainButton_2    matlab.ui.control.Button
        TimeDomainButton_2         matlab.ui.control.Button
        RateButtonGroup            matlab.ui.container.ButtonGroup
        OriginalButton             matlab.ui.control.RadioButton
        DoubleButton               matlab.ui.control.RadioButton
        HalfButton                 matlab.ui.control.RadioButton
        UIAxes2_2                  matlab.ui.control.UIAxes
        SavecompositeButton        matlab.ui.control.Button
        BandsDropDownLabel         matlab.ui.control.Label
        BandsDropDown              matlab.ui.control.DropDown
        FilterSpecificationButton  matlab.ui.control.Button
        TimeDomainButton_3         matlab.ui.control.Button
        FrequencyDomainButton_3    matlab.ui.control.Button
    end


    properties (Access = public)
        gain_170 = 0; 
        gain_310 = 0;
        gain_600 = 0;
        gain_1K = 0;
        gain_3K = 0;
        gain_6K = 0;
        gain_12K = 0;
        gain_14K = 0;
        gain_16K = 0;
        rate = 0;
        Fs;
        inputSamples;
        Bsignal_1;
        Bsignal_2;
        Bsignal_3;
        Bsignal_4;
        Bsignal_5;
        Bsignal_6;
        Bsignal_7;
        Bsignal_8;
        Bsignal_9;
        signal_1;
        signal_2;
        signal_3;
        signal_4;
        signal_5;
        signal_6;
        signal_7;
        signal_8;
        signal_9;
        composite_signal;
        band_1;
        band_2;
        band_3;
        band_4;
        band_5;
        band_6;
        band_7;
        band_8;
        band_9;
        currentBand = 1;
    end

    methods (Access = private)
    
        function Filter(app)
             app.Bsignal_1=filter(app.band_1, app.inputSamples);
             app.Bsignal_2=filter(app.band_2, app.inputSamples);
             app.Bsignal_3=filter(app.band_3, app.inputSamples);
             app.Bsignal_4=filter(app.band_4, app.inputSamples);
             app.Bsignal_5=filter(app.band_5, app.inputSamples);
             app.Bsignal_6=filter(app.band_6, app.inputSamples);
             app.Bsignal_7=filter(app.band_7, app.inputSamples);
             app.Bsignal_8=filter(app.band_8, app.inputSamples);
             app.Bsignal_9=filter(app.band_9, app.inputSamples);  
            
        end
        
        function GainFilteredSignal(app)
            app.signal_1 = app.Bsignal_1*(10^(app.gain_170/20));
            app.signal_2 = app.Bsignal_2*(10^(app.gain_310/20));
            app.signal_3 = app.Bsignal_3*(10^(app.gain_600/20));
            app.signal_4 = app.Bsignal_4*(10^(app.gain_1K/20));
            app.signal_5 = app.Bsignal_5*(10^(app.gain_3K/20));
            app.signal_6 = app.Bsignal_6*(10^(app.gain_6K/20));
            app.signal_7 = app.Bsignal_7*(10^(app.gain_12K/20));
            app.signal_8 = app.Bsignal_8*(10^(app.gain_14K/20));
            app.signal_9 = app.Bsignal_9*(10^(app.gain_16K/20));
            app.composite_signal = app.signal_1 +app.signal_2 + app.signal_3 +app.signal_4 + app.signal_5 + app.signal_6 + app.signal_7 + app.signal_8 + app.signal_9;
        end
    end


    methods (Access = private)

        % Value changed function: BandsDropDown
        function BandsDropDownValueChanged(app, event)
            value = app.BandsDropDown.Value;
                switch value 
                    case '0 - 170'
                        app.currentBand = 1;
                    case '170 - 310'
                        app.currentBand = 2;
                    case '310 - 600'
                        app.currentBand = 3;
                    case '600 - 1000'
                        app.currentBand = 4;
                    case '1K - 3K'
                        app.currentBand = 5;
                    case '3K - 6K'
                        app.currentBand = 6;
                    case '6K - 12K'
                        app.currentBand = 7;
                    case '12K - 14K'
                        app.currentBand = 8;
                    case '14K - 16K'
                        app.currentBand = 9;
                end 
           
                
        end

        % Button pushed function: FilterSpecificationButton
        function FilterSpecificationButtonPushed(app, event)
            switch app.currentBand 
                case 1
                    grpdelay(app.band_1);
                case 2
                    grpdelay(app.band_2);
                case 3
                    grpdelay(app.band_3);
                case 4
                    grpdelay(app.band_4);
                case 5
                    grpdelay(app.band_5);
                case 6
                    grpdelay(app.band_6);
                case 7
                    grpdelay(app.band_7);
                case 8
                    grpdelay(app.band_8);
                case 9
                    grpdelay(app.band_9);
            end 
        end

        % Value changed function: FilterTypeDropDown
        function FilterTypeDropDownValueChanged(app, event)
            value = app.FilterTypeDropDown.Value;
            switch value 
                case 'IIR' 
                     app.band_1 = IIR_ButterWorth(app.Fs,0,170);
                     app.band_2 = IIR_ButterWorth(app.Fs,170,310);
                     app.band_3 = IIR_ButterWorth(app.Fs,310,600);
                     app.band_4 = IIR_ButterWorth(app.Fs,600,1000);
                     app.band_5 = IIR_ButterWorth(app.Fs,1000,3000);
                     app.band_6 = IIR_ButterWorth(app.Fs,3000,6000);
                     app.band_7 = IIR_ButterWorth(app.Fs,6000,12000);
                     app.band_8 = IIR_ButterWorth(app.Fs,12000,14000);
                     app.band_9 = IIR_ButterWorth(app.Fs,14000,16000);
                     app.Filter();
                     app.GainFilteredSignal();
                case 'FIR Window'
                     app.band_1 = FIR_Window(app.Fs,0,170);
                     app.band_2 = FIR_Window(app.Fs,170,310);
                     app.band_3 = FIR_Window(app.Fs,310,600);
                     app.band_4 = FIR_Window(app.Fs,600,1000);
                     app.band_5 = FIR_Window(app.Fs,1000,3000);
                     app.band_6 = FIR_Window(app.Fs,3000,6000);
                     app.band_7 = FIR_Window(app.Fs,6000,12000);
                     app.band_8 = FIR_Window(app.Fs,12000,14000);
                     app.band_9 = FIR_Window(app.Fs,14000,16000);
                     app.Filter();
                     app.GainFilteredSignal();
                otherwise 
            end 
        end

        % Button pushed function: FrequencyDomainButton
        function FrequencyDomainButtonPushed(app, event)
            cla(app.UIAxes2);
            hold (app.UIAxes2,'on')
            Nfft = length(app.inputSamples);
            f=linspace(0, app.Fs, Nfft);
            G=abs(fft(app.inputSamples, Nfft));
            plot(app.UIAxes2,f(1:Nfft/2), G(1:Nfft/2));
            hold(app.UIAxes2, 'off');
        end

        % Button pushed function: FrequencyDomainButton_2
        function FrequencyDomainButton_2Pushed(app, event)
            cla(app.UIAxes2_2);
            hold (app.UIAxes2_2,'on')
            Nfft = length(app.composite_signal);
            f=linspace(0, app.Fs, Nfft);
            G=abs(fft(app.composite_signal, Nfft));
            plot(app.UIAxes2_2,f(1:Nfft/2), G(1:Nfft/2));
            hold(app.UIAxes2_2, 'off');
        end

        % Button pushed function: FrequencyDomainButton_3
        function FrequencyDomainButton_3Pushed(app, event)
             switch app.currentBand 
                case 1
                    Nfft = length(app.Bsignal_1);
                    f=linspace(0, app.Fs, Nfft);
                    G=abs(fft(app.Bsignal_1, Nfft));
                    plot(f(1:Nfft/2), G(1:Nfft/2));
                case 2
                   Nfft = length(app.Bsignal_2);
                    f=linspace(0, app.Fs, Nfft);
                    G=abs(fft(app.Bsignal_2, Nfft));
                    plot(f(1:Nfft/2), G(1:Nfft/2));
                case 3
                     Nfft = length(app.Bsignal_3);
                    f=linspace(0, app.Fs, Nfft);
                    G=abs(fft(app.Bsignal_3, Nfft));
                    plot(f(1:Nfft/2), G(1:Nfft/2));
                case 4
                     Nfft = length(app.Bsignal_4);
                    f=linspace(0, app.Fs, Nfft);
                    G=abs(fft(app.Bsignal_4, Nfft));
                    plot(f(1:Nfft/2), G(1:Nfft/2));
                case 5
                      Nfft = length(app.Bsignal_5);
                    f=linspace(0, app.Fs, Nfft);
                    G=abs(fft(app.Bsignal_5, Nfft));
                    plot(f(1:Nfft/2), G(1:Nfft/2));
                case 6
                     Nfft = length(app.Bsignal_6);
                    f=linspace(0, app.Fs, Nfft);
                    G=abs(fft(app.Bsignal_6, Nfft));
                    plot(f(1:Nfft/2), G(1:Nfft/2));
                case 7
                     Nfft = length(app.Bsignal_7);
                    f=linspace(0, app.Fs, Nfft);
                    G=abs(fft(app.Bsignal_7, Nfft));
                    plot(f(1:Nfft/2), G(1:Nfft/2));
                case 8
                   Nfft = length(app.Bsignal_8);
                    f=linspace(0, app.Fs, Nfft);
                    G=abs(fft(app.Bsignal_8, Nfft));
                    plot(f(1:Nfft/2), G(1:Nfft/2));
                case 9
                     Nfft = length(app.Bsignal_9);
                    f=linspace(0, app.Fs, Nfft);
                    G=abs(fft(app.Bsignal_9, Nfft));
                    plot(f(1:Nfft/2), G(1:Nfft/2));
            end 
        end

        % Value changed function: KSlider_14
        function KSlider_14ValueChanged(app, event)
            app.gain_14K = app.KSlider_14.Value;
            
        end

        % Value changed function: KSlider_16
        function KSlider_16ValueChanged(app, event)
            app.gain_16K = app.KSlider_16.Value;
            
        end

        % Button pushed function: PlayCompositeButton
        function PlayCompositeButtonPushed(app, event)
            sound(app.composite_signal, app.rate);
            
        end

        % Selection changed function: RateButtonGroup
        function RateButtonGroupSelectionChanged(app, event)
                choise = app.RateButtonGroup.SelectedObject;
                switch choise
                    case app.OriginalButton
                      app.rate = app.Fs;
                    case app.DoubleButton
                      app.rate = (app.Fs)*2;
                    case app.HalfButton
                        disp(choise);
                      app.rate = (app.Fs)/2;
                    otherwise
                       error('Algorithm not supported'); 
                end          
            
        end

        % Size changed function: RateButtonGroup
        function RateButtonGroupSizeChanged(app, event)
            position = app.RateButtonGroup.Position;
            
        end

        % Button pushed function: SavecompositeButton
        function SavecompositeButtonPushed(app, event)
            audiowrite('Composite.wav',app.composite_signal,app.Fs);
        end

        % Value changed function: Slider_170
        function Slider_170ValueChanged(app, event)
            app.gain_170 = app.Slider_170.Value;
            
        end

        % Value changed function: Slider_310
        function Slider_310ValueChanged(app, event)
            app.gain_310 = app.Slider_310.Value;
            
        end

        % Value changed function: Slider_600
        function Slider_600ValueChanged(app, event)
            app.gain_600 = app.Slider_600.Value;
            
        end

        % Button pushed function: TimeDomainButton
        function TimeDomainButtonPushed(app, event)
            cla(app.UIAxes2);
            hold (app.UIAxes2,'on')
            t = linspace(0,length(app.inputSamples)/app.Fs,length(app.inputSamples));
            plot(app.UIAxes2,t,app.inputSamples);
            hold(app.UIAxes2, 'off');
        end

        % Button pushed function: TimeDomainButton_2
        function TimeDomainButton_2Pushed(app, event)
            cla(app.UIAxes2_2);
            hold (app.UIAxes2_2,'on')
            t = linspace(0,length(app.composite_signal)/app.Fs,length(app.composite_signal));
            plot(app.UIAxes2_2,t,app.composite_signal);
            hold(app.UIAxes2_2, 'off');
        end

        % Button pushed function: TimeDomainButton_3
        function TimeDomainButton_3Pushed(app, event)
            switch app.currentBand 
                case 1
                   t = linspace(0,length(app.Bsignal_1)/app.Fs,length(app.Bsignal_1));
                   plot(t,app.Bsignal_1);  
                case 2
                     t = linspace(0,length(app.Bsignal_2)/app.Fs,length(app.Bsignal_2));
                     plot(t,app.Bsignal_2);
                case 3
                     t = linspace(0,length(app.Bsignal_3)/app.Fs,length(app.Bsignal_3));
                     plot(t,app.Bsignal_3);
                case 4
                     t = linspace(0,length(app.Bsignal_4)/app.Fs,length(app.Bsignal_4));
                      plot(t,app.Bsignal_4);
                case 5
                     t = linspace(0,length(app.Bsignal_5)/app.Fs,length(app.Bsignal_5));
                     plot(t,app.Bsignal_5);
                case 6
                     t = linspace(0,length(app.Bsignal_6)/app.Fs,length(app.Bsignal_6));
                     plot(t,app.Bsignal_6);
                case 7
                     t = linspace(0,length(app.Bsignal_7)/app.Fs,length(app.Bsignal_7));
                     plot(t,app.Bsignal_7);
                case 8
                   t = linspace(0,length(app.Bsignal_8)/app.Fs,length(app.Bsignal_8));
                   plot(t,app.Bsignal_8);
                case 9
                     t = linspace(0,length(app.Bsignal_9)/app.Fs,length(app.Bsignal_9));
                     plot(t,app.Bsignal_9);
            end 
        end

        % Button pushed function: baseButton
        function baseButtonPushed(app, event)
             app.gain_170 = 12;
             app.Slider_170.Value = 12;
             app.gain_310 = 0;
             app.Slider_310.Value = 0;
             app.gain_600 = 0; 
             app.Slider_600.Value = 0;
             app.gain_1K = 0;
             app.kSlider_1.Value = 0;
             app.gain_3K = 0;
             app.kSlider_3.Value = 0;
             app.gain_6K = 0;
             app.kSlider_6.Value = 0;
             app.gain_12K = 0;
             app.kSlider_12.Value = 0;
             app.gain_14K = 0;
             app.KSlider_14.Value = 0;
             app.gain_16K = 0;
             app.KSlider_16.Value = 0;        
        end

        % Button pushed function: inputsignalButton
        function inputsignalButtonPushed(app, event)
              [file,filename] = uigetfile('*.wav');
              [app.inputSamples,app.Fs]= audioread(file);
              app.rate = app.Fs;
              
        end

        % Value changed function: kSlider_12
        function kSlider_12ValueChanged(app, event)
            app.gain_12K = app.kSlider_12.Value;
            
        end

        % Value changed function: kSlider_1
        function kSlider_1ValueChanged(app, event)
            app.gain_1K = app.kSlider_1.Value;
            
        end

        % Value changed function: kSlider_3
        function kSlider_3ValueChanged(app, event)
            app.gain_3K = app.kSlider_3.Value;
            
        end

        % Value changed function: kSlider_6
        function kSlider_6ValueChanged(app, event)
            app.gain_6K = app.kSlider_6.Value;
            
        end

        % Button pushed function: playoriginalButton
        function playoriginalButtonPushed(app, event)
            sound(app.inputSamples, app.rate);
            
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.KSliderLabel = uilabel(app.UIFigure);
            app.KSliderLabel.Text = '16K';
            app.KSlider_16 = uislider(app.UIFigure);
            app.KSlider_16.Limits = [-12 12];
            app.KSlider_16.MajorTickLabels = {'', '', '', '', ''};
            app.KSlider_16.ValueChangedFcn = createCallbackFcn(app, @KSlider_16ValueChanged, true);
            app.KSlider_2Label = uilabel(app.UIFigure);
            app.KSlider_2Label.Text = '14 K ';
            app.KSlider_14 = uislider(app.UIFigure);
            app.KSlider_14.Limits = [-12 12];
            app.KSlider_14.MajorTickLabels = {'', '', '', '', ''};
            app.KSlider_14.ValueChangedFcn = createCallbackFcn(app, @KSlider_14ValueChanged, true);
            app.kSliderLabel = uilabel(app.UIFigure);
            app.kSliderLabel.Text = '12k ';
            app.kSlider_12 = uislider(app.UIFigure);
            app.kSlider_12.Limits = [-12 12];
            app.kSlider_12.MajorTickLabels = {'', '', '', '', ''};
            app.kSlider_12.ValueChangedFcn = createCallbackFcn(app, @kSlider_12ValueChanged, true);
            app.kSlider_2Label = uilabel(app.UIFigure);
            app.kSlider_2Label.Text = '6k';
            app.kSlider_6 = uislider(app.UIFigure);
            app.kSlider_6.Limits = [-12 12];
            app.kSlider_6.MajorTickLabels = {'', '', '', '', ''};
            app.kSlider_6.ValueChangedFcn = createCallbackFcn(app, @kSlider_6ValueChanged, true);
            app.kSlider_3Label = uilabel(app.UIFigure);
            app.kSlider_3Label.Text = '3k';
            app.kSlider_3 = uislider(app.UIFigure);
            app.kSlider_3.Limits = [-12 12];
            app.kSlider_3.MajorTickLabels = {'', '', '', '', ''};
            app.kSlider_3.ValueChangedFcn = createCallbackFcn(app, @kSlider_3ValueChanged, true);
            app.kSlider_4Label = uilabel(app.UIFigure);
            app.kSlider_4Label.Text = '1k';
            app.kSlider_1 = uislider(app.UIFigure);
            app.kSlider_1.Limits = [-12 12];
            app.kSlider_1.MajorTickLabels = {'', '', '', '', ''};
            app.kSlider_1.ValueChangedFcn = createCallbackFcn(app, @kSlider_1ValueChanged, true);
            app.SliderLabel = uilabel(app.UIFigure);
            app.SliderLabel.Text = '600';
            app.Slider_600 = uislider(app.UIFigure);
            app.Slider_600.Limits = [-12 12];
            app.Slider_600.MajorTickLabels = {'', '', '', '', ''};
            app.Slider_600.ValueChangedFcn = createCallbackFcn(app, @Slider_600ValueChanged, true);
            app.Slider_2Label = uilabel(app.UIFigure);
            app.Slider_2Label.Text = '310';
            app.Slider_310 = uislider(app.UIFigure);
            app.Slider_310.Limits = [-12 12];
            app.Slider_310.MajorTickLabels = {'', '', '', '', ''};
            app.Slider_310.ValueChangedFcn = createCallbackFcn(app, @Slider_310ValueChanged, true);
            app.Slider_3Label = uilabel(app.UIFigure);
            app.Slider_3Label.Text = '170';
            app.Slider_170 = uislider(app.UIFigure);
            app.Slider_170.Limits = [-12 12];
            app.Slider_170.MajorTickLabels = {'', '', '', '', ''};
            app.Slider_170.ValueChangedFcn = createCallbackFcn(app, @Slider_170ValueChanged, true);
            app.FilterTypeDropDownLabel = uilabel(app.UIFigure);
            app.FilterTypeDropDownLabel.Text = 'Filter Type ';
            app.FilterTypeDropDown = uidropdown(app.UIFigure);
            app.FilterTypeDropDown.Items = {'IIR', 'FIR Window', 'FIR EQURipple'};
            app.FilterTypeDropDown.ValueChangedFcn = createCallbackFcn(app, @FilterTypeDropDownValueChanged, true);
            app.FilterTypeDropDown.Value = 'IIR';
            app.inputsignalButton = uibutton(app.UIFigure, 'push');
            app.inputsignalButton.ButtonPushedFcn = createCallbackFcn(app, @inputsignalButtonPushed, true);
            app.inputsignalButton.Text = 'input signal ';
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'Original ')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            app.baseButton = uibutton(app.UIFigure, 'push');
            app.baseButton.ButtonPushedFcn = createCallbackFcn(app, @baseButtonPushed, true);
            app.baseButton.Text = 'base ';
            app.playoriginalButton = uibutton(app.UIFigure, 'push');
            app.playoriginalButton.ButtonPushedFcn = createCallbackFcn(app, @playoriginalButtonPushed, true);
            app.playoriginalButton.Text = 'play original ';
            app.FrequencyDomainButton = uibutton(app.UIFigure, 'push');
            app.FrequencyDomainButton.ButtonPushedFcn = createCallbackFcn(app, @FrequencyDomainButtonPushed, true);
            app.FrequencyDomainButton.Text = 'Frequency Domain';
            app.DBLabel = uilabel(app.UIFigure);
            app.DBLabel.Text = '+ 12 DB ';
            app.DBLabel_2 = uilabel(app.UIFigure);
            app.DBLabel_2.Text = '+ 0 DB';
            app.DBLabel_3 = uilabel(app.UIFigure);
            app.DBLabel_3.Text = '- 12 DB ';
            app.TimeDomainButton = uibutton(app.UIFigure, 'push');
            app.TimeDomainButton.ButtonPushedFcn = createCallbackFcn(app, @TimeDomainButtonPushed, true);
            app.TimeDomainButton.Text = 'Time Domain ';
            app.PlayCompositeButton = uibutton(app.UIFigure, 'push');
            app.PlayCompositeButton.ButtonPushedFcn = createCallbackFcn(app, @PlayCompositeButtonPushed, true);
            app.PlayCompositeButton.Text = 'Play Composite ';
            app.FrequencyDomainButton_2 = uibutton(app.UIFigure, 'push');
            app.FrequencyDomainButton_2.ButtonPushedFcn = createCallbackFcn(app, @FrequencyDomainButton_2Pushed, true);
            app.FrequencyDomainButton_2.Text = 'Frequency Domain ';
            app.TimeDomainButton_2 = uibutton(app.UIFigure, 'push');
            app.TimeDomainButton_2.ButtonPushedFcn = createCallbackFcn(app, @TimeDomainButton_2Pushed, true);
            app.TimeDomainButton_2.Text = 'Time Domain ';
            app.RateButtonGroup = uibuttongroup(app.UIFigure);
            app.RateButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @RateButtonGroupSelectionChanged, true);
            app.RateButtonGroup.Title = 'Rate ';
            app.RateButtonGroup.SizeChangedFcn = createCallbackFcn(app, @RateButtonGroupSizeChanged, true);
            app.OriginalButton = uiradiobutton(app.RateButtonGroup);
            app.OriginalButton.Text = 'Original ';
            app.OriginalButton.Value = true;
            app.DoubleButton = uiradiobutton(app.RateButtonGroup);
            app.DoubleButton.Text = 'Double ';
            app.HalfButton = uiradiobutton(app.RateButtonGroup);
            app.HalfButton.Text = 'Half ';
            app.UIAxes2_2 = uiaxes(app.UIFigure);
            title(app.UIAxes2_2, 'Filter ')
            xlabel(app.UIAxes2_2, 'X')
            ylabel(app.UIAxes2_2, 'Y')
            app.UIAxes2_2.XLim = [0 1];
            app.UIAxes2_2.XLimMode = 'manual';
            app.UIAxes2_2.YLim = [0 1];
            app.UIAxes2_2.YLimMode = 'manual';
            app.UIAxes2_2.CLim = [0 1];
            app.UIAxes2_2.CLimMode = 'manual';
            app.UIAxes2_2.XTick = [0 0.2 0.4 0.6 0.8 1];
            app.UIAxes2_2.YTick = [0 0.2 0.4 0.6 0.8 1];
            app.SavecompositeButton = uibutton(app.UIFigure, 'push');
            app.SavecompositeButton.ButtonPushedFcn = createCallbackFcn(app, @SavecompositeButtonPushed, true);
            app.SavecompositeButton.Text = 'Save composite ';
            app.BandsDropDownLabel = uilabel(app.UIFigure);
            app.BandsDropDownLabel.Text = 'Bands';
            app.BandsDropDown = uidropdown(app.UIFigure);
            app.BandsDropDown.Items = {'0 - 170', '170 - 310', '310 - 600', '600 - 1000', '1K - 3K', '3K - 6K', '6K - 12K', '12K - 14K', '14K - 16K'};
            app.BandsDropDown.ValueChangedFcn = createCallbackFcn(app, @BandsDropDownValueChanged, true);
            app.BandsDropDown.Value = '0 - 170';
            app.FilterSpecificationButton = uibutton(app.UIFigure, 'push');
            app.FilterSpecificationButton.ButtonPushedFcn = createCallbackFcn(app, @FilterSpecificationButtonPushed, true);
            app.FilterSpecificationButton.Text = 'Filter Specification ';
            app.TimeDomainButton_3 = uibutton(app.UIFigure, 'push');
            app.TimeDomainButton_3.ButtonPushedFcn = createCallbackFcn(app, @TimeDomainButton_3Pushed, true);
            app.TimeDomainButton_3.Text = 'Time Domain ';
            app.FrequencyDomainButton_3 = uibutton(app.UIFigure, 'push');
            app.FrequencyDomainButton_3.ButtonPushedFcn = createCallbackFcn(app, @FrequencyDomainButton_3Pushed, true);
            app.FrequencyDomainButton_3.Text = 'Frequency Domain';
        end
    end

    methods (Access = public)

        % Construct app
        function app = app1

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end